classdef PartsSpin < matlab.ui.componentcontainer.ComponentContainer

	% Properties that correspond to underlying components
	properties (Access = private, Transient, NonCopyable)
		GridLayout  matlab.ui.container.GridLayout
		Button_2    matlab.ui.control.Button
		Button      matlab.ui.control.Button
	end

	methods (Access = protected)
		
		% パブリック プロパティの値が変更されたときに実行されるコード
		function update(comp)
			% 基となるコンポーネントを更新するには、この関数を使用します
			
		end

		% 基となるコンポーネントの作成
		function setup(comp)

			comp.Position = [1 1 25 34];
			comp.BackgroundColor = [0.94 0.94 0.94];

			% Create GridLayout
			comp.GridLayout = uigridlayout(comp);
			comp.GridLayout.ColumnWidth = {'1x'};
			comp.GridLayout.ColumnSpacing = 0;
			comp.GridLayout.RowSpacing = 0;
			comp.GridLayout.Padding = [0 0 0 0];

			% Create Button
			comp.Button = uibutton(comp.GridLayout, 'push');
			comp.Button.FontSize = 8;
			comp.Button.Layout.Row = 1;
			comp.Button.Layout.Column = 1;
			comp.Button.Text = '▲';

			% Create Button_2
			comp.Button_2 = uibutton(comp.GridLayout, 'push');
			comp.Button_2.FontSize = 8;
			comp.Button_2.Layout.Row = 2;
			comp.Button_2.Layout.Column = 1;
			comp.Button_2.Text = '▼';
		end
	end
end